classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Button_2               matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        zeropadEditField_2     matlab.ui.control.EditField
        zeropadLabel           matlab.ui.control.Label
        zeropadEditField       matlab.ui.control.EditField
        zeropadEditFieldLabel  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        Spinner                matlab.ui.control.Spinner
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            im = double(imread('leiko-12202022201359-68.png'));
            im = imresize(im,app.Spinner.Value);
            [h,w] = size(im);
            
            h1 = str2num(app.zeropadEditField.Value);
            w1 = str2num(app.zeropadEditField_2.Value);
            zer = zeros(h1,w1);

            zer(h1/2-h/2:h1/2+h/2-1,w1/2-w/2:w1/2+w/2-1) = im;
            
            im = zer;
            imshow(im,[],'parent',app.UIAxes2)
            
            [h,w] = size(im);
            
            I1c=im(1:2:h,1:2:w); % фаза 3pi/2
            I2c=im(1:2:h,2:2:w); % фаза pi
            I3c=im(2:2:h,1:2:w); % фаза 0
            I4c=im(2:2:h,2:2:w); % фаза pi/2
            
            Hc=(I3c-I2c)+1i*(I4c-I1c); % матрица интерферирующих волн
            
            phi = angle(Hc);
            
            lambda = 532e-9;
            px = 2*3.45e-6;
            T = zeros(h/2,w/2,11);
            T(:,:,1) = Hc;
            for i=1:10
                z = 0.01;
                T(:,:,i+1) = ASL_mex(T(:,:,i), -z, lambda, px);
                imagesc(abs(T(:,:,i+1)),'parent',app.UIAxes);
                app.Label_2.Text = int2str(i);
                pause(0.01)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            imagesc(abs(T(:,:,round(app.Slider.Value/100*10))),'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100];
            app.Slider.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Коэф растяжения';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.Value = 0.25;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Распространение';
            app.zeropadEditFieldLabel = uilabel(app.UIFigure);
            app.zeropadEditFieldLabel.Text = 'Высота zeropad';
            app.zeropadEditField = uieditfield(app.UIFigure, 'text');
            app.zeropadEditField.Value = '4096';
            app.zeropadLabel = uilabel(app.UIFigure);
            app.zeropadLabel.Text = 'Ширина zeropad';
            app.zeropadEditField_2 = uieditfield(app.UIFigure, 'text');
            app.zeropadEditField_2.Value = '4896';
            app.Label_2 = uilabel(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Выбрать кадр';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end